classdef DeProj_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InputsPanel               matlab.ui.container.Panel
        segLocDisplay             matlab.ui.control.TextArea
        curveLocDisplay           matlab.ui.control.TextArea
        ResizeelevationmapmaximumsizepixEditField  matlab.ui.control.NumericEditField
        ResizemeshmaximumsizefacesEditField  matlab.ui.control.NumericEditField
        AxialstepsizeumEditField  matlab.ui.control.NumericEditField
        outputFolderDisplay       matlab.ui.control.TextArea
        DisplaysPanel             matlab.ui.container.Panel
    end


    properties (Access = public)
        doDispSeg = true;
        doDispMesh = true;
        doDispOverlay = true;
        axPixSize = 0.5;
        tiffImSize = 40000;
        maxFaces = 3000;
        outputFolder = '';
        segLoc = '';
        curveLoc = '';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AxialstepsizeumEditField
        function AxialstepsizeumEditFieldValueChanged(app, event)
            app.axPixSize = app.AxialstepsizeumEditField.Value;            
        end

        % Callback function
        function CurvaturetypeDropDownValueChanged(app, event)
            curvatureType = app.CurvaturetypeDropDown.Value;
            if strcmp(curvatureType,'2D elevation map (.tif)')
                app.ResizeelevationmapmaximumsizepixEditField.Enable = 'on';
                app.AxialstepsizeumEditField.Enable = 'on';
            elseif strcmp(curvatureType,'3D mesh (.ply)')
                app.ResizeelevationmapmaximumsizepixEditField.Enable = 'off';
                app.AxialstepsizeumEditField.Enable = 'off';
            end
        end

        % Callback function
        function DisplayanoverlayoftheoriginalinputsCheckBoxValueChanged(app, event)
            app.doDispOverlay = app.DisplayanoverlayoftheoriginalinputsCheckBox.Value;
        end

        % Callback function
        function Displaytheoriginal2DsegmentationCheckBoxValueChanged(app, event)
            app.doDispSeg = app.Displaytheoriginal2DsegmentationCheckBox.Value;
        end

        % Callback function
        function Displaytheoriginal3DcurvatureCheckBoxValueChanged(app, event)
            app.doDispMesh = app.Displaytheoriginal3DcurvatureCheckBox.Value;
        end

        % Value changed function: 
        % ResizeelevationmapmaximumsizepixEditField
        function ResizeelevationmapmaximumsizepixEditFieldValueChanged(app, event)
            app.tiffImSize = app.ResizeelevationmapmaximumsizepixEditField.Value;
        end

        % Value changed function: 
        % ResizemeshmaximumsizefacesEditField
        function ResizemeshmaximumsizefacesEditFieldValueChanged(app, event)
            app.maxFaces = app.ResizemeshmaximumsizefacesEditField.Value;            
        end

        % Callback function
        function RunDeprojectionButtonPushed(app, event)
            fprintf('app.doDispSeg = %s\n',app.doDispSeg);
            fprintf('app.doDispMesh = %s\n',app.doDispMesh);
            fprintf('app.doDispOverlay = %s\n',app.doDispOverlay);
            fprintf('app.axPixSize = %d\n',app.axPixSize);
            fprintf('app.tiffImSize = %d\n',app.tiffImSize);
            fprintf('app.maxFaces = %d\n',app.maxFaces);
            fprintf('app.outputFolder = %s\n',app.outputFolder);
            fprintf('app.segLoc = %s\n',app.segLoc);
            fprintf('app.curveLoc = %s\n',app.curveLoc);
            surface3D_combine(app.doDispSeg,...
                app.doDispMesh,...
                app.doDispOverlay,...
                app.axPixSize,...
                app.tiffImSize,...
                app.maxFaces,...
                app.outputFolder,...
                app.segLoc,...
                app.curveLoc);
        end

        % Callback function
        function SelectcurvaturefileplyortifButtonPushed(app, event)
            [file, path] = uigetfile({'*.ply' ; '*.tif'}, 'Select the file containing the sample curvature');
%             if isequal(file,0)
%                disp('User selected Cancel');
%             else
%                disp(['User selected ', fullfile(path,file)]);
%             end
            app.curveLoc = fullfile(path,file);
            app.curveLocDisplay.Value = app.curveLoc;
        end

        % Callback function
        function SelectoutputfolderButtonPushed(app, event)
            app.outputFolder = uigetdir(pwd,'Select the output folder');
            app.outputFolderDisplay.Value = app.outputFolder;
        end

        % Callback function
        function SelectsegmentationfileButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat'}, 'Select the file containing the segmentation');
            app.segLoc = fullfile(path,file);
            app.segLocDisplay.Value = app.segLoc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.segLocDisplay = uitextarea(app.InputsPanel);
            app.segLocDisplay.Editable = 'off';
            app.curveLocDisplay = uitextarea(app.InputsPanel);
            app.curveLocDisplay.Editable = 'off';
            app.ResizeelevationmapmaximumsizepixEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ResizeelevationmapmaximumsizepixEditField.ValueDisplayFormat = '%.0f';
            app.ResizeelevationmapmaximumsizepixEditField.ValueChangedFcn = createCallbackFcn(app, @ResizeelevationmapmaximumsizepixEditFieldValueChanged, true);
            app.ResizeelevationmapmaximumsizepixEditField.Value = 40000;
            app.ResizemeshmaximumsizefacesEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ResizemeshmaximumsizefacesEditField.ValueDisplayFormat = '%.0f';
            app.ResizemeshmaximumsizefacesEditField.ValueChangedFcn = createCallbackFcn(app, @ResizemeshmaximumsizefacesEditFieldValueChanged, true);
            app.ResizemeshmaximumsizefacesEditField.Value = 3000;
            app.AxialstepsizeumEditField = uieditfield(app.InputsPanel, 'numeric');
            app.AxialstepsizeumEditField.ValueDisplayFormat = '%.3f';
            app.AxialstepsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @AxialstepsizeumEditFieldValueChanged, true);
            app.AxialstepsizeumEditField.Value = 0.5;
            app.outputFolderDisplay = uitextarea(app.InputsPanel);
            app.outputFolderDisplay.Editable = 'off';
            app.DisplaysPanel = uipanel(app.UIFigure);
            app.DisplaysPanel.Title = 'Displays';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeProj_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end